classdef CiftiStorm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        NewprocessMenu          matlab.ui.container.Menu
        DatasetMenu_3           matlab.ui.container.Menu
        ListMenu                matlab.ui.container.Menu
        LoadMenu                matlab.ui.container.Menu
        DeleteMenu              matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        ToolsMenu               matlab.ui.container.Menu
        ViewMenu                matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        UpdateMenu              matlab.ui.container.Menu
        UsermanualMenu          matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        Toolbar                 matlab.ui.container.Toolbar
        PushToolNewProcess      matlab.ui.container.toolbar.PushTool
        PushToolDefault_Params  matlab.ui.container.toolbar.PushTool
        PushToolListDataset     matlab.ui.container.toolbar.PushTool
        PushToolExplorerDS      matlab.ui.container.toolbar.PushTool
        PushTool                matlab.ui.container.toolbar.PushTool
        LabelBreadcrumbs        matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        TabGroup                matlab.ui.container.TabGroup
        TabExplorer             matlab.ui.container.Tab
        TreeInput               matlab.ui.container.Tree
        TabDatasets             matlab.ui.container.Tab
        TreeOutput              matlab.ui.container.Tree
    end

    
    properties (Access = private)
        cfs_properties % Description
        ciftistormDir % Description
    end
    
    methods (Access = private)
        % Explore the Tree Folder
        function ExploreFolder(app,nodeData)
            app.cfs_properties = get_properties();
            app.TreeInput.Children.delete;
            if(ismember(nodeData.name,{'..'}))
                rootDir = fileparts(nodeData.folder);                              
                dirName = strsplit(rootDir,filesep);
                dirName = dirName{end};                
            else
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
            end 
            app.Breadcrumbs(rootDir)
            parent = uitreenode(app.TreeInput,'Text',dirName);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(rootDir);        
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);                    
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';                        
                    end                    
                    % Contex menu
                    cm = uicontextmenu(app.UIFigure);
                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@DefineProperties,'Anatomy',node.NodeData});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@DefineProperties,'Non-brain',node.NodeData});
                    node.ContextMenu = cm;
                    node.Icon = icon_file;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', file_list(i).name);      
                    node.Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);
        end
        % Create the Breadcrumbs in the navigation tree
        function Breadcrumbs(app,path)
            pathParts = strsplit(path,filesep);            
            text = strcat(filesep, " ");            
            for i=2:length(pathParts)
                if(i < length(pathParts))
                    text = strcat(text, pathParts{i}," ",filesep," ");                    
                else
                    text = strcat(text, pathParts{i}); 
                end  
            end
            app.LabelBreadcrumbs.Text = strcat("<font style='color:blue;'>",text,"</font>"); 
            app.LabelBreadcrumbs.Interpreter = 'html';
        end        
        % Fill a TreeNode with subfolders
        function FillFolder(app,node)
            nodeData = node.NodeData;
            if(~ismember(nodeData.name,{'..'}) && ~ismember(nodeData.name,{'.'}))                
                rootDir = fullfile(nodeData.folder,nodeData.name);
                dirName = nodeData.name;
                subfile_list = dir(rootDir);
                subfile_list(ismember({subfile_list.name},{'.','..'})) = [];
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/folder.png';
                            subnode.NodeData = subfile_list(j);
                            sub_cm = uicontextmenu(app.UIFigure);
                            m1 = uimenu(sub_cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@DefineProperties,'Anatomy',node.NodeData});
                            m2 = uimenu(sub_cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@DefineProperties,'Non-brain',node.NodeData});
                            subnode.ContextMenu = sub_cm;
                        end
                    end
                end
                for j=1:length(subfile_list)
                    subfile = subfile_list(j);
                    if(~subfile.isdir)
                        children = node.Children;
                        if(isempty(children) || all(~ismember({children.Text},subfile.name)))
                            subnode = uitreenode(node, 'Text', subfile.name);
                            subnode.Icon = 'guide/images/file.jpeg';
                            subnode.NodeData = subfile_list(j);
                        end
                    end
                end
            end
        end
        
        function create_CFS_db(app)
            app.ciftistormDir = fullfile(getUserDir(),'.ciftistorm');
            if(~isfolder(app.ciftistormDir))
                mkdir(app.ciftistormDir);
            end
            if(~isfolder(fullfile(app.ciftistormDir,'defaults')))
                mkdir(fullfile(app.ciftistormDir,'defaults'));
            end
            if(~isfolder(fullfile(app.ciftistormDir,'defaults','anatomy')))
                mkdir(fullfile(app.ciftistormDir,'defaults','anatomy'));
            end
            if(~isfolder(fullfile(app.ciftistormDir,'defaults','eeg')))
                mkdir(fullfile(app.ciftistormDir,'defaults','eeg'));
                mkdir(fullfile(app.ciftistormDir,'defaults','eeg','Colin27'));
                mkdir(fullfile(app.ciftistormDir,'defaults','eeg','ICBM152'));
                mkdir(fullfile(app.ciftistormDir,'defaults','eeg','NotAligned'));
            end
            if(~isfolder(fullfile(app.ciftistormDir,'defaults','meg')))
                mkdir(fullfile(app.ciftistormDir,'defaults','meg'));
            end
        end        
        
    end
    
    methods (Access = public)
        
        function DefineProperties(app,event,action,clasif,nodeData)
            switch clasif
                case 'Anatomy'
                    base_path = fullfile(nodeData.folder,nodeData.name); 
                    app.cfs_properties.anatomy_params.anatomy_type.type_list{2}.base_path = base_path;  
                    saveJSON(app.bcv_properties.general_params,'cfs_properties/process_import_anat.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
                    h.smartIndentContents
                    h.save
                    h.close
                case 'Non-brain'
                    base_path = fullfile(nodeData.folder,nodeData.name); 
                    app.cfs_properties.anatomy_params.common_params.non_brain_surfaces.base_path = base_path; 
                    saveJSON(app.bcv_properties.general_params,'cfs_properties/process_import_anat.json');
                    h = matlab.desktop.editor.openDocument(fullfile(pwd,'cfs_properties/process_import_anat.json'));
                    h.smartIndentContents
                    h.save
                    h.close
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            userDir = getUserDir();
            app.create_CFS_db();            
            app.Breadcrumbs(userDir)
            parent = uitreenode(app.TreeInput,'Text',userDir);
            parent.Icon = 'guide/images/folder.png';
            file_list = dir(userDir);
            file_list(ismember({file_list.name},{'.'})) = [];
            for i=1:length(file_list)
                fileStruct = file_list(i);                
                if(fileStruct.isdir)
                    node = uitreenode(parent, 'Text', fileStruct.name);
                    node.NodeData = fileStruct;
                    if(isequal(fileStruct.name,'..'))
                        icon_file = 'guide/images/folder_parent.png';
                    else
                        icon_file = 'guide/images/folder.png';                        
                    end                    
                    % Context menu
                    cm = uicontextmenu(app.UIFigure);                    
                    m1 = uimenu(cm,"Text","Define as Anatomy folder","MenuSelectedFcn",{@app.DefineProperties,'Anatomy',fileStruct});
                    m2 = uimenu(cm,"Text","Define as Non-Brain folder","MenuSelectedFcn",{@app.DefineProperties,'Non-brain',fileStruct});
                    node.ContextMenu = cm;
                    node.Icon = icon_file;
                end
            end
            for i=1:length(file_list)
                fileStruct = file_list(i);
                if(~fileStruct.isdir)
                    node = uitreenode(parent, 'Text', fileStruct.name);      
                    node.Icon = 'guide/images/file.jpeg';
                end
            end
            expand(parent);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Menu selected function: NewprocessMenu
        function NewprocessMenuSelected(app, event)
            obj_configure = Params_configuration();
        end

        % Double-clicked callback: TreeInput
        function TreeInputDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                ExploreFolder(app,node.NodeData);
            end
        end

        % Clicked callback: TreeInput
        function TreeInputClicked(app, event)
            node = event.InteractionInformation.Node;
            if(node.NodeData.isdir)
                FillFolder(app,node);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewprocessMenu = uimenu(app.FileMenu);
            app.NewprocessMenu.MenuSelectedFcn = createCallbackFcn(app, @NewprocessMenuSelected, true);
            app.NewprocessMenu.Text = 'New process';
            app.DatasetMenu_3 = uimenu(app.FileMenu);
            app.DatasetMenu_3.Text = 'Dataset';
            app.ListMenu = uimenu(app.DatasetMenu_3);
            app.ListMenu.Text = 'List';
            app.LoadMenu = uimenu(app.DatasetMenu_3);
            app.LoadMenu.Text = 'Load';
            app.DeleteMenu = uimenu(app.DatasetMenu_3);
            app.DeleteMenu.Text = 'Delete';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.UpdateMenu = uimenu(app.HelpMenu);
            app.UpdateMenu.Text = 'Update';
            app.UsermanualMenu = uimenu(app.HelpMenu);
            app.UsermanualMenu.Text = 'User manual';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushToolNewProcess = uipushtool(app.Toolbar);
            app.PushToolNewProcess.Tooltip = {'New process'};
            app.PushToolNewProcess.Icon = fullfile(pathToMLAPP, 'images', 'new_process.jpeg');
            app.PushToolDefault_Params = uipushtool(app.Toolbar);
            app.PushToolDefault_Params.Tooltip = {'Set default params'};
            app.PushToolDefault_Params.Icon = fullfile(pathToMLAPP, 'images', 'default.png');
            app.PushToolListDataset = uipushtool(app.Toolbar);
            app.PushToolListDataset.Tooltip = {'List Datasets'};
            app.PushToolListDataset.Icon = fullfile(pathToMLAPP, 'images', 'list_dataset.png');
            app.PushToolExplorerDS = uipushtool(app.Toolbar);
            app.PushToolExplorerDS.Tooltip = {'Explore Datasets'};
            app.PushToolExplorerDS.Icon = fullfile(pathToMLAPP, 'images', 'explore.png');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.Tooltip = {'Run process'};
            app.PushTool.Icon = fullfile(pathToMLAPP, '', 'images', 'run.jpg');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabExplorer = uitab(app.TabGroup);
            app.TabExplorer.Title = 'Explorer';
            app.TreeInput = uitree(app.TabExplorer);
            app.TreeInput.ClickedFcn = createCallbackFcn(app, @TreeInputClicked, true);
            app.TreeInput.DoubleClickedFcn = createCallbackFcn(app, @TreeInputDoubleClicked, true);
            app.TabDatasets = uitab(app.TabGroup);
            app.TabDatasets.Title = 'Datasets';
            app.TreeOutput = uitree(app.TabDatasets);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.LabelBreadcrumbs = uilabel(app.UIFigure);
            app.LabelBreadcrumbs.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CiftiStorm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end