classdef Dataset_configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        SetpropertiesButton             matlab.ui.control.Button
        ParamsconfigurationPanel        matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        GeneralsTab                     matlab.ui.container.Tab
        DependenciesPanel               matlab.ui.container.Panel
        SelectBSTButton                 matlab.ui.control.Button
        SelectBST_dbButton              matlab.ui.control.Button
        SelectSPMButton                 matlab.ui.control.Button
        BraninstormpathEditFieldLabel   matlab.ui.control.Label
        BraninstormpathEditField        matlab.ui.control.EditField
        BrainstormdbpathEditFieldLabel  matlab.ui.control.Label
        BrainstormdbpathEditField       matlab.ui.control.EditField
        SPM12pathEditFieldLabel         matlab.ui.control.Label
        SPM12pathEditField              matlab.ui.control.EditField
        OutputconfigurationsPanel       matlab.ui.container.Panel
        SelectWorkspaceButton           matlab.ui.control.Button
        SelectReportsButton             matlab.ui.control.Button
        WorkspacepathEditFieldLabel     matlab.ui.control.Label
        WorkspacepathEditField          matlab.ui.control.EditField
        ReportspathEditFieldLabel       matlab.ui.control.Label
        ReportspathEditField            matlab.ui.control.EditField
        GeneralsparamsPanel             matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        ModalityDropDownLabel           matlab.ui.control.Label
        ModalityDropDown                matlab.ui.control.DropDown
        ProtocolnameEditFieldLabel      matlab.ui.control.Label
        ProtocolnameEditField           matlab.ui.control.EditField
        SubjectsperprotocolEditFieldLabel  matlab.ui.control.Label
        SubjectsperprotocolEditField    matlab.ui.control.NumericEditField
        AnatomicalstructureTab          matlab.ui.container.Tab
        AnatomyconfigurationButtonGroup  matlab.ui.container.ButtonGroup
        Button_BST_Default              matlab.ui.control.RadioButton
        Button_HCP_Template             matlab.ui.control.RadioButton
        Button_HCP_Individual           matlab.ui.control.RadioButton
        BSTDefaultPanel                 matlab.ui.container.Panel
        TemplatenameDropDownLabel       matlab.ui.control.Label
        AnatomyTemplateDropDown         matlab.ui.control.DropDown
        HCPTemplatePanel                matlab.ui.container.Panel
        HCPIndividualPanel              matlab.ui.container.Panel
        ChannelTab                      matlab.ui.container.Tab
        ImportdataTab                   matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function checked = check_visual_params(app)
            checked = true;
            if(isempty(app.BraninstormpathEditField.Value))
                msgbox({'The BrainStorm toolbox path can not be empty.',...
                    ' Please select a correct Brainstorm path.'},'Info');
                checked = false;
            end
            if(isempty(app.BrainstormdbpathEditField.Value))
                msgbox({'The BrainStorm db path can not be empty.',...
                    ' Please select a correct DB path.'},'Info');
                app.BrainstormdbpathEditField.Value = 'local';
                checked = false;
            end
            if(isempty(app.SPM12pathEditField.Value))
                msgbox({'The SPM12 path can not be empty.',...
                    ' Please select a correct SPM12 path.'},'Info');
                checked = false;
            end
            if(isempty(app.WorkspacepathEditField.Value))
                msgbox({'The Workspace path can not be empty.',...
                    ' Please select a correct Workspace path.'},'Info');
                checked = false;
            end
            if(isempty(app.ReportspathEditField.Value))
                msgbox({'The Reports path can not be empty.',...
                    ' Please select a correct Reports path.'},'Info');
                checked = false;
            end
            if(isempty(app.ProtocolnameEditField.Value))
                msgbox({'The Protocol name field can not be empty.',...
                    ' Please type a correct Protocol name.'},'Info');
                checked = false;
            end
        end       
        
        function load_default_params(app)
            app.BSTDefaultPanel.Enable = 'on';
            app.HCPTemplatePanel.Enable = 'off';
            app.HCPIndividualPanel.Enable = 'off';
            get_bst_default_anatomy(app);
        end
        
        function get_bst_default_anatomy(app)
            % loading anatomical templates
            defaults = jsondecode(fileread(fullfile('bst_templates','bst_default_anatomy.json')));
            app.AnatomyTemplateDropDown.Items = {defaults.name};
            app.AnatomyTemplateDropDown.Items{end+1} = '-Select a template-';
            app.AnatomyTemplateDropDown.Value = '-Select a template-';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('app');
            addpath('bst_templates');
            addpath('config_protocols');
            addpath('external');
            addpath(genpath('functions'));
            addpath('external');
            load_default_params(app);            
        end

        % Button pushed function: SelectBSTButton
        function SelectBSTButtonPushed(app, event)
            try
                folder = uigetdir("Select the Brinstorm path");
                if(isfile(fullfile(folder,'brainstorm.m')))
                    app.BrainstormdbpathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the BrainStorm toolbox.',...
                        ' Please select a correct Brainstorm path.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: SelectSPMButton
        function SelectSPMButtonPushed(app, event)
            try
                folder = uigetdir("Select the SPM12 path");
                if(isfile(fullfile(folder,'spm.m')))
                    app.BrainstormdbpathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the SPM12 toolbox.',...
                        ' Please select a correct SPM12 path.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: SelectBST_dbButton
        function SelectBST_dbButtonPushed(app, event)
            try
                folder = uigetdir("Select the BST db folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.BrainstormdbpathEditField.Value = folder;
                else
                    app.BrainstormdbpathEditField.Value = 'local';
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: SelectWorkspaceButton
        function SelectWorkspaceButtonPushed(app, event)
            try
                folder = uigetdir("Select the Output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.BrainstormdbpathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: SelectReportsButton
        function SelectReportsButtonPushed(app, event)
            try
                folder = uigetdir("Select the Reports output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.BrainstormdbpathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Selection changed function: 
        % AnatomyconfigurationButtonGroup
        function AnatomyconfigurationButtonGroupSelectionChanged(app, event)
            if(app.Button_BST_Default.Value)
                app.BSTDefaultPanel.Enable = 'on';
                app.HCPTemplatePanel.Enable = 'off';
                app.HCPIndividualPanel.Enable = 'off';
            end
            if(app.Button_HCP_Template.Value)
                app.BSTDefaultPanel.Enable = 'off';
                app.HCPTemplatePanel.Enable = 'on';
                app.HCPIndividualPanel.Enable = 'off';
            end
            if(app.Button_HCP_Individual.Value)
                app.BSTDefaultPanel.Enable = 'off';
                app.HCPTemplatePanel.Enable = 'off';
                app.HCPIndividualPanel.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.SetpropertiesButton = uibutton(app.UIFigure, 'push');
            app.SetpropertiesButton.Text = 'Set properties';
            app.ParamsconfigurationPanel = uipanel(app.UIFigure);
            app.ParamsconfigurationPanel.Title = 'Params configuration';
            app.TabGroup = uitabgroup(app.ParamsconfigurationPanel);
            app.GeneralsTab = uitab(app.TabGroup);
            app.GeneralsTab.Title = 'Generals';
            app.DependenciesPanel = uipanel(app.GeneralsTab);
            app.DependenciesPanel.Title = 'Dependencies';
            app.SelectBSTButton = uibutton(app.DependenciesPanel, 'push');
            app.SelectBSTButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBSTButtonPushed, true);
            app.SelectBSTButton.Text = 'Select';
            app.SelectBST_dbButton = uibutton(app.DependenciesPanel, 'push');
            app.SelectBST_dbButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBST_dbButtonPushed, true);
            app.SelectBST_dbButton.Text = 'Select';
            app.SelectSPMButton = uibutton(app.DependenciesPanel, 'push');
            app.SelectSPMButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSPMButtonPushed, true);
            app.SelectSPMButton.Text = 'Select';
            app.BraninstormpathEditFieldLabel = uilabel(app.DependenciesPanel);
            app.BraninstormpathEditFieldLabel.Text = 'Braninstorm path:';
            app.BraninstormpathEditField = uieditfield(app.DependenciesPanel, 'text');
            app.BrainstormdbpathEditFieldLabel = uilabel(app.DependenciesPanel);
            app.BrainstormdbpathEditFieldLabel.Text = 'Brainstorm db path:';
            app.BrainstormdbpathEditField = uieditfield(app.DependenciesPanel, 'text');
            app.BrainstormdbpathEditField.Value = 'local';
            app.SPM12pathEditFieldLabel = uilabel(app.DependenciesPanel);
            app.SPM12pathEditFieldLabel.Text = 'SPM12 path:';
            app.SPM12pathEditField = uieditfield(app.DependenciesPanel, 'text');
            app.OutputconfigurationsPanel = uipanel(app.GeneralsTab);
            app.OutputconfigurationsPanel.Title = 'Output configurations';
            app.SelectWorkspaceButton = uibutton(app.OutputconfigurationsPanel, 'push');
            app.SelectWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkspaceButtonPushed, true);
            app.SelectWorkspaceButton.Text = 'Select';
            app.SelectReportsButton = uibutton(app.OutputconfigurationsPanel, 'push');
            app.SelectReportsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReportsButtonPushed, true);
            app.SelectReportsButton.Text = 'Select';
            app.WorkspacepathEditFieldLabel = uilabel(app.OutputconfigurationsPanel);
            app.WorkspacepathEditFieldLabel.Text = 'Workspace path:';
            app.WorkspacepathEditField = uieditfield(app.OutputconfigurationsPanel, 'text');
            app.ReportspathEditFieldLabel = uilabel(app.OutputconfigurationsPanel);
            app.ReportspathEditFieldLabel.Text = 'Reports path:';
            app.ReportspathEditField = uieditfield(app.OutputconfigurationsPanel, 'text');
            app.GeneralsparamsPanel = uipanel(app.GeneralsTab);
            app.GeneralsparamsPanel.Title = 'Generals params';
            app.Label = uilabel(app.GeneralsparamsPanel);
            app.Label.Text = '';
            app.ModalityDropDownLabel = uilabel(app.GeneralsparamsPanel);
            app.ModalityDropDownLabel.Text = 'Modality:';
            app.ModalityDropDown = uidropdown(app.GeneralsparamsPanel);
            app.ModalityDropDown.Items = {'--Select--', 'EEG', 'MEG'};
            app.ModalityDropDown.Value = '--Select--';
            app.ProtocolnameEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.ProtocolnameEditFieldLabel.Text = 'Protocol name:';
            app.ProtocolnameEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.SubjectsperprotocolEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.SubjectsperprotocolEditFieldLabel.Text = 'Subjects per protocol:';
            app.SubjectsperprotocolEditField = uieditfield(app.GeneralsparamsPanel, 'numeric');
            app.SubjectsperprotocolEditField.Limits = [5 100];
            app.SubjectsperprotocolEditField.Value = 10;
            app.AnatomicalstructureTab = uitab(app.TabGroup);
            app.AnatomicalstructureTab.Title = 'Anatomical structure';
            app.AnatomyconfigurationButtonGroup = uibuttongroup(app.AnatomicalstructureTab);
            app.AnatomyconfigurationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnatomyconfigurationButtonGroupSelectionChanged, true);
            app.AnatomyconfigurationButtonGroup.Title = 'Anatomy configuration';
            app.Button_BST_Default = uiradiobutton(app.AnatomyconfigurationButtonGroup);
            app.Button_BST_Default.Text = '';
            app.Button_BST_Default.Value = true;
            app.Button_HCP_Template = uiradiobutton(app.AnatomyconfigurationButtonGroup);
            app.Button_HCP_Template.Text = '';
            app.Button_HCP_Individual = uiradiobutton(app.AnatomyconfigurationButtonGroup);
            app.Button_HCP_Individual.Text = '';
            app.BSTDefaultPanel = uipanel(app.AnatomyconfigurationButtonGroup);
            app.BSTDefaultPanel.Title = 'BST Default';
            app.TemplatenameDropDownLabel = uilabel(app.BSTDefaultPanel);
            app.TemplatenameDropDownLabel.Text = 'Template name:';
            app.AnatomyTemplateDropDown = uidropdown(app.BSTDefaultPanel);
            app.HCPTemplatePanel = uipanel(app.AnatomyconfigurationButtonGroup);
            app.HCPTemplatePanel.Title = 'HCP Template';
            app.HCPIndividualPanel = uipanel(app.AnatomyconfigurationButtonGroup);
            app.HCPIndividualPanel.Title = 'HCP Individual';
            app.ChannelTab = uitab(app.TabGroup);
            app.ChannelTab.Title = 'Channel';
            app.ImportdataTab = uitab(app.TabGroup);
            app.ImportdataTab.Title = 'Import data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dataset_configuration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end