classdef Params_configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParamsconfigurationPanel        matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        GeneralsTab                     matlab.ui.container.Tab
        GeneralsparamsPanel             matlab.ui.container.Panel
        WorkspacepathEditField          matlab.ui.control.EditField
        WorkspacepathEditFieldLabel     matlab.ui.control.Label
        BSTpathEditField                matlab.ui.control.EditField
        BraninstormpathLabel            matlab.ui.control.Label
        BSTdbpathEditField              matlab.ui.control.EditField
        BrainstormdbpathLabel           matlab.ui.control.Label
        SelectWorkspaceButton           matlab.ui.control.Button
        SelectBST_dbButton              matlab.ui.control.Button
        SelectBSTButton                 matlab.ui.control.Button
        AfterMaQCSwitch                 matlab.ui.control.Switch
        AfterMaQCSwitchLabel            matlab.ui.control.Label
        ResetprotocolSwitch             matlab.ui.control.Switch
        ResetprotocolSwitchLabel        matlab.ui.control.Label
        ProtocolnameEditField           matlab.ui.control.EditField
        ProtocolnameLabel               matlab.ui.control.Label
        ModalityDropDown                matlab.ui.control.DropDown
        ModalityLabel                   matlab.ui.control.Label
        ImportAnatomyTab                matlab.ui.container.Tab
        CommonparamsPanel               matlab.ui.container.Panel
        HCPTemplatenameEditField_6      matlab.ui.control.EditField
        filenameLabel                   matlab.ui.control.Label
        MRItransformationLabel_2        matlab.ui.control.Label
        AnatomyTemplateDropDown_3       matlab.ui.control.DropDown
        LayerdescriptorLabel            matlab.ui.control.Label
        CortexEditField                 matlab.ui.control.NumericEditField
        CortexEditFieldLabel            matlab.ui.control.Label
        SkullEditField                  matlab.ui.control.NumericEditField
        SkullEditFieldLabel             matlab.ui.control.Label
        HeadEditField                   matlab.ui.control.NumericEditField
        HeadEditFieldLabel              matlab.ui.control.Label
        HCPIndividNBPathEditField       matlab.ui.control.EditField
        NonbrainsurfacesLabel           matlab.ui.control.Label
        ImportPrepBPEditField_2         matlab.ui.control.EditField
        pathLabel                       matlab.ui.control.Label
        NoverticesLabel                 matlab.ui.control.Label
        SelectCHPTemplatePathButton     matlab.ui.control.Button
        SelectImpPrepBPButton_2         matlab.ui.control.Button
        SelectAnatomyoptionButtonGroup  matlab.ui.container.ButtonGroup
        HCPIndividualPanel              matlab.ui.container.Panel
        HCPTemplatenameEditField_3      matlab.ui.control.EditField
        AtlasfilenameLabel              matlab.ui.control.Label
        HCPTemplatenameEditField_2      matlab.ui.control.EditField
        Tw1filenameLabel                matlab.ui.control.Label
        HCPIndividPathEditField         matlab.ui.control.EditField
        AnatomypathLabel_2              matlab.ui.control.Label
        SelectCHPIndividPathButton      matlab.ui.control.Button
        HCPTemplatePanel                matlab.ui.container.Panel
        HCPTemplatenameEditField_5      matlab.ui.control.EditField
        AtlasfilenameLabel_2            matlab.ui.control.Label
        HCPTemplatenameEditField_4      matlab.ui.control.EditField
        Tw1filenameLabel_2              matlab.ui.control.Label
        HCPTemplatenameEditField        matlab.ui.control.EditField
        TemplatenameEditFieldLabel      matlab.ui.control.Label
        HCPTemplPathEditField           matlab.ui.control.EditField
        AnatomypathLabel                matlab.ui.control.Label
        SelectCHPTemplPathButton        matlab.ui.control.Button
        BSTDefaultAnatomyPanel          matlab.ui.container.Panel
        AnatomyTemplateDropDown_2       matlab.ui.control.DropDown
        AtlasLabel                      matlab.ui.control.Label
        AnatomyTemplateDropDown         matlab.ui.control.DropDown
        TemplatenameDropDownLabel       matlab.ui.control.Label
        Button_HCP_Individual           matlab.ui.control.RadioButton
        Button_HCP_Template             matlab.ui.control.RadioButton
        Button_BST_Default              matlab.ui.control.RadioButton
        ImportChannelTab                matlab.ui.container.Tab
        SelectChanneloptionButtonGroup  matlab.ui.container.ButtonGroup
        UseChannelRawdataPanel          matlab.ui.container.Panel
        IsFileSwitch                    matlab.ui.control.Switch
        IsFileSwitchLabel               matlab.ui.control.Label
        ChanFormatDropDown              matlab.ui.control.DropDown
        FormatDropDownLabel             matlab.ui.control.Label
        ChanRawDataFPathEditField       matlab.ui.control.EditField
        NameLabel                       matlab.ui.control.Label
        ChanRawDataBPathEditField       matlab.ui.control.EditField
        BasepathLabel                   matlab.ui.control.Label
        SelectRawDataBPathButton        matlab.ui.control.Button
        UseChanneltemplatePanel         matlab.ui.container.Panel
        Channel_UITable                 matlab.ui.control.Table
        ChannTemplatenameDropDown       matlab.ui.control.DropDown
        TemplatenameDropDownLabel_2     matlab.ui.control.Label
        ChannGroupDropDown              matlab.ui.control.DropDown
        GroupDropDownLabel              matlab.ui.control.Label
        Button_Channel_raw_data         matlab.ui.control.RadioButton
        Button_Channel_template         matlab.ui.control.RadioButton
        ComputeHeadmodelTab             matlab.ui.container.Tab
        UsedefaultparametersSwitch      matlab.ui.control.Switch
        UsedefaultparametersSwitchLabel  matlab.ui.control.Label
        CommonparamsPanel_2             matlab.ui.container.Panel
        BemSelectEditField              matlab.ui.control.EditField
        BemSelectEditFieldLabel         matlab.ui.control.Label
        BemCondEditField                matlab.ui.control.EditField
        BemCondEditFieldLabel           matlab.ui.control.Label
        BemNamesEditField               matlab.ui.control.EditField
        BemNamesEditFieldLabel          matlab.ui.control.Label
        ConductivityEditField           matlab.ui.control.EditField
        ConductivityEditFieldLabel      matlab.ui.control.Label
        RadiiEditField                  matlab.ui.control.EditField
        RadiiEditFieldLabel             matlab.ui.control.Label
        MethodsButtonGroup              matlab.ui.container.ButtonGroup
        PrepUseRawDataPanel             matlab.ui.container.Panel
        DownsampleSpinner               matlab.ui.control.Spinner
        DownsampleSpinnerLabel          matlab.ui.control.Label
        NodeShiftSpinner                matlab.ui.control.Spinner
        NodeShiftSpinnerLabel           matlab.ui.control.Label
        NbVerticesSpinner               matlab.ui.control.Spinner
        NbVerticesSpinnerLabel          matlab.ui.control.Label
        VertexDensitySpinner            matlab.ui.control.Spinner
        VertexDensitySpinnerLabel       matlab.ui.control.Label
        MergeMethodDropDown             matlab.ui.control.DropDown
        MergeMethodDropDownLabel        matlab.ui.control.Label
        KeepRatioSpinner                matlab.ui.control.Spinner
        KeepRatioSpinnerLabel           matlab.ui.control.Label
        MaxVolSpinner                   matlab.ui.control.Spinner
        MaxVolSpinnerLabel              matlab.ui.control.Label
        MeshTypeDropDown                matlab.ui.control.DropDown
        MeshTypeDropDownLabel           matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        UseTensorSwitch                 matlab.ui.control.Switch
        UseTensorSwitchLabel            matlab.ui.control.Label
        IsotropicSwitch                 matlab.ui.control.Switch
        IsotropicSwitchLabel            matlab.ui.control.Label
        FemSelectEditField              matlab.ui.control.EditField
        FemSelectEditFieldLabel         matlab.ui.control.Label
        FemCondEditField                matlab.ui.control.EditField
        FemCondEditFieldLabel           matlab.ui.control.Label
        OpenMEEGBEMPanel_2              matlab.ui.container.Panel
        SplitLengthSpinner              matlab.ui.control.Spinner
        SplitLengthSpinnerLabel         matlab.ui.control.Label
        isSplitSwitch                   matlab.ui.control.Switch
        isSplitSwitchLabel              matlab.ui.control.Label
        isAdaptativeSwitch              matlab.ui.control.Switch
        isAdaptativeSwitchLabel         matlab.ui.control.Label
        isAdjointSwitch                 matlab.ui.control.Switch
        isAdjointSwitchLabel            matlab.ui.control.Label
        OverlappingSpheresPanel         matlab.ui.container.Panel
        Button_PreUseRawData            matlab.ui.control.RadioButton
        Button_ImportPrepData           matlab.ui.control.RadioButton
        Button_DoNotImportData          matlab.ui.control.RadioButton
        SetpropertiesButton             matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function checked = check_visual_params(app)
            checked = true;
            if(isempty(app.BSTpathEditField.Value))
                msgbox({'The BrainStorm toolbox path can not be empty.',...
                    ' Please select a correct Brainstorm path.'},'Info');
                checked = false;
            end
            if(isempty(app.BSTdbpathEditField.Value))
                msgbox({'The BrainStorm db path can not be empty.',...
                    ' Please select a correct DB path.'},'Info');
                app.BSTdbpathEditField.Value = 'local';
                checked = false;
            end
            if(isempty(app.SPM12pathEditField.Value))
                msgbox({'The SPM12 path can not be empty.',...
                    ' Please select a correct SPM12 path.'},'Info');
                checked = false;
            end
            if(isempty(app.WorkspacepathEditField.Value))
                msgbox({'The Workspace path can not be empty.',...
                    ' Please select a correct Workspace path.'},'Info');
                checked = false;
            end
            if(isempty(app.BSToutputpathEditField.Value))
                msgbox({'The Reports path can not be empty.',...
                    ' Please select a correct Reports path.'},'Info');
                checked = false;
            end
            if(isempty(app.ProtocolnameEditField.Value))
                msgbox({'The Protocol name field can not be empty.',...
                    ' Please type a correct Protocol name.'},'Info');
                checked = false;
            end
        end
        
        function load_default_params(app)
            app.BSTDefaultAnatomyPanel.Enable = 'on';
            app.HCPTemplatePanel.Enable = 'off';
            app.HCPIndividualPanel.Enable = 'off';
            get_bst_default_anatomy(app);
            
            app.UseChanneltemplatePanel.Enable = 'on';
            app.UseChannelRawdataPanel.Enable = 'off';
            get_bst_default_channel(app);
            
            % Preprocessed data tab
            
        end
        
        function get_bst_default_anatomy(app)
            % loading anatomical templates
            defaults = jsondecode(fileread(fullfile('bst_templates','bst_default_anatomy.json')));
            app.AnatomyTemplateDropDown.Items = {defaults.name};
            app.AnatomyTemplateDropDown.Items{end+1} = '--Select a template--';
            app.AnatomyTemplateDropDown.Value = '--Select a template--';
        end
        
        function get_bst_default_channel(app)
            defaults = jsondecode(fileread(fullfile('bst_templates','bst_layout_default.json')));
            app.ChannGroupDropDown.Items = {defaults.name};
            app.ChannGroupDropDown.Items{end+1} = '--Select--';
            app.ChannGroupDropDown.Value = '--Select--';
            app.ChannTemplatenameDropDown.Items = {'--Select a template--'};
            app.ChannTemplatenameDropDown.Value = '--Select a template--';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'Dataset Configuration';
            addpath('app');
            addpath('bst_templates');
            addpath('config_protocols');
            addpath('external');
            addpath(genpath('functions'));
            addpath('external');
            load_default_params(app);
        end

        % Button pushed function: SelectBSTButton
        function SelectBSTButtonPushed(app, event)
            try
                folder = uigetdir("Select the Brinstorm path");
                if(isfile(fullfile(folder,'brainstorm.m')))
                    app.BSTpathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the BrainStorm toolbox.',...
                        folder,...
                        ' Please select a correct Brainstorm path.'},'Info');
                    app.BSTpathEditField.Value = '';
                end
            catch
            end
        end

        % Callback function
        function SelectSPMButtonPushed(app, event)
            try
                folder = uigetdir("Select the SPM12 path");
                if(isfile(fullfile(folder,'spm.m')))
                    app.SPM12pathEditField.Value = folder;
                else
                    msgbox({'The selected folder do not contain the SPM12 toolbox.',...
                        folser,...
                        ' Please select a correct SPM12 path.'},'Info');
                    app.SPM12pathEditField.Value = '';
                end
            catch
            end
        end

        % Button pushed function: SelectBST_dbButton
        function SelectBST_dbButtonPushed(app, event)
            try
                folder = uigetdir("Select the BST db folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.BSTdbpathEditField.Value = folder;
                else
                    app.BSTdbpathEditField.Value = 'local';
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Button pushed function: SelectWorkspaceButton
        function SelectWorkspaceButtonPushed(app, event)
            try
                folder = uigetdir("Select the Output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.WorkspacepathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Callback function
        function SelectReportsButtonPushed(app, event)
            try
                folder = uigetdir("Select the Reports output folder");
                [~,values] = fileattrib(folder);
                if(values.UserWrite)
                    app.BSToutputpathEditField.Value = folder;
                else
                    msgbox({'The current user do not have write permissions on the selected forder.',...
                        folder,...
                        ' Please check the folder permission or select another output folder.'},'Info');
                end
            catch
            end
        end

        % Selection changed function: SelectAnatomyoptionButtonGroup
        function SelectAnatomyoptionButtonGroupSelectionChanged(app, event)
            if(app.Button_BST_Default.Value)
                app.BSTDefaultAnatomyPanel.Enable = 'on';
                app.HCPTemplatePanel.Enable = 'off';
                app.HCPIndividualPanel.Enable = 'off';
            end
            if(app.Button_HCP_Template.Value)
                app.BSTDefaultAnatomyPanel.Enable = 'off';
                app.HCPTemplatePanel.Enable = 'on';
                app.HCPIndividualPanel.Enable = 'off';
            end
            if(app.Button_HCP_Individual.Value)
                app.BSTDefaultAnatomyPanel.Enable = 'off';
                app.HCPTemplatePanel.Enable = 'off';
                app.HCPIndividualPanel.Enable = 'on';
            end
        end

        % Value changed function: ChannGroupDropDown
        function ChannGroupDropDownValueChanged(app, event)
            group_name = app.ChannGroupDropDown.Value;
            if(isequal(app.ModalityDropDown.Value,'--Select--'))
                msgbox('Please select the modality first in Generals Tab.','Info');
                app.ChannGroupDropDown.Value = '--Select--';
                return;
            end
            if(isempty(app.BSTpathEditField.Value))
                msgbox('Please select the Brainstorm path in Generals Tab.','Info');
                app.ChannGroupDropDown.Value = '--Select--';
                return;
            end
            if(~isequal(group_name,'--Select--'))
                defaults = jsondecode(fileread(fullfile('bst_templates','bst_layout_default.json')));
                layouts_name = defaults(find(ismember({defaults.name},group_name),1)).contents;
                app.ChannTemplatenameDropDown.Items = {layouts_name.name};
                app.ChannTemplatenameDropDown.Items{end+1} = '--Select a template--';
                app.ChannTemplatenameDropDown.Value = '--Select a template--';
                msgbox({'Be sure to select the mode and direction of the BrainStorm in <<Generals Tab>>, before selecting the Channel Layout.'},'Info');
            else
                app.ChannTemplatenameDropDown.Items = {'--Select a template--'};
                app.ChannTemplatenameDropDown.Value = '--Select a template--';
                set(app.Channel_UITable, 'Data', []);
            end
        end

        % Selection changed function: SelectChanneloptionButtonGroup
        function SelectChanneloptionButtonGroupSelectionChanged(app, event)
            if(app.Button_Channel_template.Value)
                app.UseChanneltemplatePanel.Enable = 'on';
                app.UseChannelRawdataPanel.Enable = 'off';
            end
            if(app.Button_Channel_raw_data.Value)
                app.UseChanneltemplatePanel.Enable = 'off';
                app.UseChannelRawdataPanel.Enable = 'on';
            end
        end

        % Value changed function: ChannTemplatenameDropDown
        function ChannTemplatenameDropDownValueChanged(app, event)
            template_name = app.ChannTemplatenameDropDown.Value;
            if(~isequal(template_name,'--Select a template--'))
                template_name = strrep(template_name,' ','_');
                if(isequal(app.ModalityDropDown.Value,'--Select--'))
                    msgbox('Please select the modality in Generals Tab.','Info');
                    app.ChannTemplatenameDropDown.Value = '--Select a template--';
                    return;
                end
                if(isempty(app.BSTpathEditField.Value))
                    msgbox('Please select the Brainstorm path in Generals Tab.','Info');
                    app.ChannTemplatenameDropDown.Value = '--Select a template--';
                    return;
                end
                if(isequal(app.ModalityDropDown.Value,'EEG'))
                    layout = load(fullfile(app.BSTpathEditField.Value,'defaults','eeg',app.ChannGroupDropDown.Value,['channel_',template_name,'.mat']));
                end
                if(isequal(app.ModalityDropDown.Value,'MEG'))
                    layout = load(fullfile(app.BSTpathEditField.Value,'defaults','meg',[template_name,'.mat']));
                end
                for i=1:length(layout.Channel)
                    loc = layout.Channel(i).Loc;
                    Weight = layout.Channel(i).Weight;
                    fields(i).Locs = strcat(num2str(loc(1)),',',num2str(loc(2)),',',num2str(loc(3)));
                end
                order_layout.No         = cellstr(string(1:length(layout.Channel)))';
                order_layout.Name       = {layout.Channel.Name}';
                order_layout.Type       = {layout.Channel.Type}';
                order_layout.Position   = {fields.Locs}';
                order_layout.Comment    = {layout.Channel.Comment}';
                T                       = struct2table(order_layout);
                set(app.Channel_UITable, 'Data', T);
            else
                set(app.Channel_UITable, 'Data', []);
            end
            
        end

        % Value changed function: ModalityDropDown
        function ModalityDropDownValueChanged(app, event)
            modality = app.ModalityDropDown.Value;
            if(isequal(modality,'EEG'))
                app.Button_Channel_template.Value = true;
                app.UseChanneltemplatePanel.Enable = 'on';
                app.UseChannelRawdataPanel.Enable = 'off';
            else
                app.Button_Channel_raw_data.Value = true;
                app.UseChanneltemplatePanel.Enable = 'off';
                app.UseChannelRawdataPanel.Enable = 'on';
            end
        end

        % Button pushed function: SelectRawDataBPathButton
        function SelectRawDataBPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.ChanRawDataBPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectCHPTemplPathButton
        function SelectCHPTemplPathButtonPushed(app, event)
            try
                if(isempty(app.HCPTemplatenameEditField.Value))
                    msgbox({'The HCP template name can not be empty.',...
                        'Please type a HCP Template name.'},'Info');
                    return;
                end
                template_name = app.HCPTemplatenameEditField.Value;
                folder = uigetdir("Select the Template basepath");
                if(~isfolder(fullfile(folder,template_name)))
                    msgbox({strcat("There is no folder with ",template_name," in the selected HCP Template folder."),...
                        'Please selcct a correct HCP Template folder or check the Template name filed.'},'Info');
                    app.HCPTemplPathEditField.Value = '';
                    return;
                end
                if(~isfile(fullfile(folder,template_name,'T1w','T1w.nii.gz')))
                    msgbox({'The template folder is not a HCP structure.',...
                        'Please check the HCP Template folder.'},'Info');
                    app.HCPTemplPathEditField.Value = '';
                    return;
                end
                app.HCPTemplPathEditField.Value = folder;
            catch
            end
        end

        % Callback function
        function SelectCHPTemplNBPathButtonPushed(app, event)
            try
                if(isempty(app.HCPTemplatenameEditField.Value))
                    msgbox({'The HCP template name can not be empty.',...
                        'Please type a HCP Template name.'},'Info');
                    return;
                end
                template_name = app.HCPTemplatenameEditField.Value;
                folder = uigetdir("Select the Template basepath");
                if(~isfolder(fullfile(folder,template_name)))
                    msgbox({strcat("There is no folder with ",template_name," in the selected Non-brain folder."),...
                        'Please selcct a correct Non-brain folder or check the Template name filed.'},'Info');
                    app.HCPTemplNBPathEditField.Value = '';
                    return;
                end
                if(~isfile(fullfile(folder,template_name,[template_name,'_outskin_mesh.nii.gz']))...
                        || ~isfile(fullfile(folder,template_name,[template_name,'_outskull_mesh.nii.gz']))...
                        || ~isfile(fullfile(folder,template_name,[template_name,'_inskull_mesh.nii.gz'])))
                msgbox({'One or more forders are not a FSL Bet output command.',...
                    'Please check the Non-brain Template folder.'},'Info');
                app.HCPTemplNBPathEditField.Value = '';
                return;
                end
                app.HCPTemplNBPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectCHPIndividPathButton
        function SelectCHPIndividPathButtonPushed(app, event)
            try
                folder = uigetdir("Select the Template basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,'T1w','T1w.nii.gz')))
                        msgbox({'One or more forders are not a HCP structure.',...
                            'Please check the HCP Template folder.'},'Info');
                        break;
                    end
                end
                app.HCPIndividPathEditField.Value = folder;
            catch
            end
        end

        % Button pushed function: SelectCHPTemplatePathButton
        function SelectCHPTemplatePathButton_4Pushed(app, event)
            try
                folder = uigetdir("Select the Template basepath");
                structures = dir(folder);
                structures(ismember( {structures.name}, {'.', '..'})) = [];  %remove . and ..
                for i=1:length(structures)
                    structure = structures(i);
                    if(~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskin_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_outskull_mesh.nii.gz']))...
                            || ~isfile(fullfile(structure.folder,structure.name,[structure.name,'_inskull_mesh.nii.gz'])))
                    msgbox({'One or more forders are not a FSL Bet output command.',...
                        'Please check the Non-brain Template folder.'},'Info');
                    break;
                    end
                end
                app.HCPIndividNBPathEditField.Value = folder;
            catch
            end
        end

        % Callback function
        function RedefinelabelsButtonPushed(app, event)
            if(isequal(app.ChannGroupDropDown.Value,'--Select--') || isequal(app.ChannTemplatenameDropDown.Value,'--Select a template--'))
                msgbox('Please select first a channel layout.','Info');
            else
                obj = Redefine_channel_labels();
                obj.load_channel_labels(app.BSTpathEditField.Value,[],app.ChannGroupDropDown.Value,app.ChannTemplatenameDropDown.Value);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Selection changed function: MethodsButtonGroup
        function MethodsButtonGroupSelectionChanged(app, event)
            if(app.Button_DoNotImportData.Value)
                app.OverlappingSpheresPanel.Enable = 'on';
                app.OpenMEEGBEMPanel_2.Enable = 'off';
                app.PrepUseRawDataPanel.Enable = 'off';
                app.CleanDataCheckBox.Enable = 'off';
                app.SelectEventsCheckBox.Enable = 'off';
                app.CleanDataCheckBox.Value = false;
                app.SelectEventsCheckBox.Value = false;
                app.CleandataconfigurationPanel.Enable = 'off';
                app.SelecteventsPanel.Enable = 'off';
            end
            if(app.Button_ImportPrepData.Value)
                app.OverlappingSpheresPanel.Enable = 'off';
                app.OpenMEEGBEMPanel_2.Enable = 'on';
                app.PrepUseRawDataPanel.Enable = 'off';
                app.CleanDataCheckBox.Enable = 'on';
                app.SelectEventsCheckBox.Enable = 'on';
            end
            if(app.Button_PreUseRawData.Value)
                if(~app.Button_Channel_raw_data.Value || isempty(app.ChanRawDataBPathEditField.Value)...
                        || isempty(app.ChanRawDataFPathEditField.Value) || isequal(app.ChanFormatDropDown.Value,'-Select-'))
                msgbox('Select and fill the raw data fields in Channel tab, before use the raw data as signal.','Info');
                app.Button_DoNotImportData.Value = true;
                return;
                else                    
                    app.OverlappingSpheresPanel.Enable = 'off';
                    app.OpenMEEGBEMPanel_2.Enable = 'off';
                    app.PrepUseRawDataPanel.Enable = 'on';
                    app.CleanDataCheckBox.Enable = 'on';
                    app.SelectEventsCheckBox.Enable = 'on';
                end
            end
        end

        % Callback function
        function CleanDataCheckBoxValueChanged(app, event)
            value = app.CleanDataCheckBox.Value;
            if(value)
                app.CleandataconfigurationPanel.Enable = 'on';
            else
                app.CleandataconfigurationPanel.Enable = 'off';
            end
        end

        % Callback function
        function SelectEventsCheckBoxValueChanged(app, event)
            value = app.SelectEventsCheckBox.Value;
            if(value)
                app.SelecteventsPanel.Enable = 'on';
            else
                app.SelecteventsPanel.Enable = 'off';
            end
        end

        % Callback function
        function SelectImpPrepBPButtonPushed(app, event)
            try
                folder = uigetdir("Select the Raw data base path");
                folder = strrep(folder,'\','/');
                app.ImportPrepBPEditField.Value = folder;
            catch
            end
        end

        % Callback function
        function SelectRawDataFPathButtonPushed(app, event)
            try
                if(isempty(app.ChanRawDataBPathEditField.Value))
                    msgbox('Select first the base path of the raw data.','Info');
                    return;
                end
                [file_name,file_path] = uigetfile({'*.mat';'*.mff';'*.edf';'*.*'},'Select a reference file to import');
                file_path = strrep(file_path,'\','/');
                base_path = app.ChanRawDataBPathEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');                    
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                ref_path = split(file_path,SubID);
                ref_path = strcat(ref_path{2},file_name);
                ref_path = strrep(ref_path,SubID,'SubID');
                app.ChanRawDataFPathEditField.Value = ref_path;
            catch
                
            end
        end

        % Callback function
        function SelectImportPrepEDButtonPushed(app, event)
            try
                if(isempty(app.ImportPrepBPEditField.Value))
                    msgbox('Select first the base path of the preprocessed data.','Info');
                    return;
                end
                [file_name,file_path] = uigetfile({'*.mat';'*.mff';'*.edf';'*.txt';'*.plg';'*.dat';'*.set';'*.*'},'Select a reference file to import');
                file_path = strrep(file_path,'\','/');
                base_path = app.ImportPrepBPEditField.Value;
                file_parts = split(file_path,base_path);
                if(isequal(length(file_parts),1))
                    msgbox({'The base path and file path do not match in the first part.', ...
                        'Please check the configuration.'},'Info');
                    return;
                end
                SubID_parts = split(file_parts{2},'/');
                SubID = SubID_parts{2};
                ref_path = split(file_path,SubID);
                ref_path = strcat(ref_path{2},file_name);
                ref_path = strrep(ref_path,SubID,'SubID');
                app.ChanRawDataFPathEditField.Value = ref_path;
            catch
                
            end
        end

        % Callback function
        function SelectCleanToolboxBPButtonPushed(app, event)
            if(isequal(app.CleanToolboxNameDropDown.Value,'--Select--'))
                msgbox('Please select the Toolbox name first','Info');
                return;
            end
             try
                folder = uigetdir("Select the clean toolbox path");
                folder = strrep(folder,'\','/');
                if(isequal(app.CleanToolboxNameDropDown.Value,'EEGLab') && isfile(fullfile(app.CleanToolboxNameDropDown.Value,'eeglab.m')))
                    app.CleanToolboxPathEditField.Value = folder;
                else
                    msgbox({'We can not find the eeglab.m file in the defined location',...
                        folder,...
                        'Please select the Toolbox path correctly.'},'Info');
                    app.CleanToolboxPathEditField.Value = '';
                    return;
                end
            catch
            end
        end

        % Callback function
        function DefineeventsButtonPushed(app, event)
            if(isequal(app.GetEventsDropDown.Value,'--Select--'))
                msgbox({'Please select first the get even ',...
                        folder,...
                        'Please select the Toolbox path correctly.'},'Info');
            else
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SetpropertiesButton = uibutton(app.UIFigure, 'push');
            app.SetpropertiesButton.Text = 'Set properties';
            app.ParamsconfigurationPanel = uipanel(app.UIFigure);
            app.ParamsconfigurationPanel.Title = 'Params configuration';
            app.TabGroup = uitabgroup(app.ParamsconfigurationPanel);
            app.GeneralsTab = uitab(app.TabGroup);
            app.GeneralsTab.Title = 'Generals';
            app.GeneralsparamsPanel = uipanel(app.GeneralsTab);
            app.GeneralsparamsPanel.Title = 'Generals params (*)';
            app.ModalityLabel = uilabel(app.GeneralsparamsPanel);
            app.ModalityLabel.Text = 'Modality:';
            app.ModalityDropDown = uidropdown(app.GeneralsparamsPanel);
            app.ModalityDropDown.Items = {'--Select--', 'EEG', 'MEG'};
            app.ModalityDropDown.ValueChangedFcn = createCallbackFcn(app, @ModalityDropDownValueChanged, true);
            app.ModalityDropDown.Value = '--Select--';
            app.ProtocolnameLabel = uilabel(app.GeneralsparamsPanel);
            app.ProtocolnameLabel.Text = 'Protocol name:';
            app.ProtocolnameEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.ResetprotocolSwitchLabel = uilabel(app.GeneralsparamsPanel);
            app.ResetprotocolSwitchLabel.Text = 'Reset protocol:';
            app.ResetprotocolSwitch = uiswitch(app.GeneralsparamsPanel, 'slider');
            app.ResetprotocolSwitch.Items = {'No', 'Yes'};
            app.ResetprotocolSwitch.Value = 'No';
            app.AfterMaQCSwitchLabel = uilabel(app.GeneralsparamsPanel);
            app.AfterMaQCSwitchLabel.Text = 'After MaQC:';
            app.AfterMaQCSwitch = uiswitch(app.GeneralsparamsPanel, 'slider');
            app.AfterMaQCSwitch.Items = {'No', 'Yes'};
            app.AfterMaQCSwitch.Value = 'No';
            app.SelectBSTButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.SelectBSTButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBSTButtonPushed, true);
            app.SelectBSTButton.Text = 'Select';
            app.SelectBST_dbButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.SelectBST_dbButton.ButtonPushedFcn = createCallbackFcn(app, @SelectBST_dbButtonPushed, true);
            app.SelectBST_dbButton.Text = 'Select';
            app.SelectWorkspaceButton = uibutton(app.GeneralsparamsPanel, 'push');
            app.SelectWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SelectWorkspaceButtonPushed, true);
            app.SelectWorkspaceButton.Text = 'Select';
            app.BrainstormdbpathLabel = uilabel(app.GeneralsparamsPanel);
            app.BrainstormdbpathLabel.Text = 'Brainstorm db path:';
            app.BSTdbpathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.BSTdbpathEditField.Value = 'local';
            app.BraninstormpathLabel = uilabel(app.GeneralsparamsPanel);
            app.BraninstormpathLabel.Text = 'Braninstorm path:';
            app.BSTpathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.WorkspacepathEditFieldLabel = uilabel(app.GeneralsparamsPanel);
            app.WorkspacepathEditFieldLabel.Text = 'Workspace path:';
            app.WorkspacepathEditField = uieditfield(app.GeneralsparamsPanel, 'text');
            app.ImportAnatomyTab = uitab(app.TabGroup);
            app.ImportAnatomyTab.Title = 'Import Anatomy';
            app.SelectAnatomyoptionButtonGroup = uibuttongroup(app.ImportAnatomyTab);
            app.SelectAnatomyoptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectAnatomyoptionButtonGroupSelectionChanged, true);
            app.SelectAnatomyoptionButtonGroup.Title = 'Select Anatomy option';
            app.Button_BST_Default = uiradiobutton(app.SelectAnatomyoptionButtonGroup);
            app.Button_BST_Default.Text = '';
            app.Button_BST_Default.Value = true;
            app.Button_HCP_Template = uiradiobutton(app.SelectAnatomyoptionButtonGroup);
            app.Button_HCP_Template.Text = '';
            app.Button_HCP_Individual = uiradiobutton(app.SelectAnatomyoptionButtonGroup);
            app.Button_HCP_Individual.Text = '';
            app.BSTDefaultAnatomyPanel = uipanel(app.SelectAnatomyoptionButtonGroup);
            app.BSTDefaultAnatomyPanel.Title = 'BST Default Anatomy';
            app.TemplatenameDropDownLabel = uilabel(app.BSTDefaultAnatomyPanel);
            app.TemplatenameDropDownLabel.Text = 'Template name:';
            app.AnatomyTemplateDropDown = uidropdown(app.BSTDefaultAnatomyPanel);
            app.AtlasLabel = uilabel(app.BSTDefaultAnatomyPanel);
            app.AtlasLabel.Text = 'Atlas:';
            app.AnatomyTemplateDropDown_2 = uidropdown(app.BSTDefaultAnatomyPanel);
            app.HCPTemplatePanel = uipanel(app.SelectAnatomyoptionButtonGroup);
            app.HCPTemplatePanel.Enable = 'off';
            app.HCPTemplatePanel.Title = 'HCP Template';
            app.SelectCHPTemplPathButton = uibutton(app.HCPTemplatePanel, 'push');
            app.SelectCHPTemplPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPTemplPathButtonPushed, true);
            app.SelectCHPTemplPathButton.Text = 'Select';
            app.AnatomypathLabel = uilabel(app.HCPTemplatePanel);
            app.AnatomypathLabel.Text = 'Anatomy path:';
            app.HCPTemplPathEditField = uieditfield(app.HCPTemplatePanel, 'text');
            app.TemplatenameEditFieldLabel = uilabel(app.HCPTemplatePanel);
            app.TemplatenameEditFieldLabel.Text = 'Template name:';
            app.HCPTemplatenameEditField = uieditfield(app.HCPTemplatePanel, 'text');
            app.Tw1filenameLabel_2 = uilabel(app.HCPTemplatePanel);
            app.Tw1filenameLabel_2.Text = 'Tw1 file name:';
            app.HCPTemplatenameEditField_4 = uieditfield(app.HCPTemplatePanel, 'text');
            app.AtlasfilenameLabel_2 = uilabel(app.HCPTemplatePanel);
            app.AtlasfilenameLabel_2.Text = 'Atlas file name:';
            app.HCPTemplatenameEditField_5 = uieditfield(app.HCPTemplatePanel, 'text');
            app.HCPIndividualPanel = uipanel(app.SelectAnatomyoptionButtonGroup);
            app.HCPIndividualPanel.Enable = 'off';
            app.HCPIndividualPanel.Title = 'HCP Individual';
            app.SelectCHPIndividPathButton = uibutton(app.HCPIndividualPanel, 'push');
            app.SelectCHPIndividPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPIndividPathButtonPushed, true);
            app.SelectCHPIndividPathButton.Text = 'Select';
            app.AnatomypathLabel_2 = uilabel(app.HCPIndividualPanel);
            app.AnatomypathLabel_2.Text = 'Anatomy path:';
            app.HCPIndividPathEditField = uieditfield(app.HCPIndividualPanel, 'text');
            app.Tw1filenameLabel = uilabel(app.HCPIndividualPanel);
            app.Tw1filenameLabel.Text = 'Tw1 file name:';
            app.HCPTemplatenameEditField_2 = uieditfield(app.HCPIndividualPanel, 'text');
            app.AtlasfilenameLabel = uilabel(app.HCPIndividualPanel);
            app.AtlasfilenameLabel.Text = 'Atlas file name:';
            app.HCPTemplatenameEditField_3 = uieditfield(app.HCPIndividualPanel, 'text');
            app.CommonparamsPanel = uipanel(app.ImportAnatomyTab);
            app.CommonparamsPanel.Title = 'Common params';
            app.SelectImpPrepBPButton_2 = uibutton(app.CommonparamsPanel, 'push');
            app.SelectImpPrepBPButton_2.Text = 'Select';
            app.SelectCHPTemplatePathButton = uibutton(app.CommonparamsPanel, 'push');
            app.SelectCHPTemplatePathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCHPTemplatePathButton_4Pushed, true);
            app.SelectCHPTemplatePathButton.Text = 'Select';
            app.NoverticesLabel = uilabel(app.CommonparamsPanel);
            app.NoverticesLabel.Text = 'No. vertices:';
            app.pathLabel = uilabel(app.CommonparamsPanel);
            app.pathLabel.Text = 'path:';
            app.ImportPrepBPEditField_2 = uieditfield(app.CommonparamsPanel, 'text');
            app.NonbrainsurfacesLabel = uilabel(app.CommonparamsPanel);
            app.NonbrainsurfacesLabel.Text = 'Non-brain surfaces:';
            app.HCPIndividNBPathEditField = uieditfield(app.CommonparamsPanel, 'text');
            app.HeadEditFieldLabel = uilabel(app.CommonparamsPanel);
            app.HeadEditFieldLabel.Text = 'Head:';
            app.HeadEditField = uieditfield(app.CommonparamsPanel, 'numeric');
            app.HeadEditField.Limits = [5000 15000];
            app.HeadEditField.Value = 8000;
            app.SkullEditFieldLabel = uilabel(app.CommonparamsPanel);
            app.SkullEditFieldLabel.Text = 'Skull:';
            app.SkullEditField = uieditfield(app.CommonparamsPanel, 'numeric');
            app.SkullEditField.Limits = [5000 15000];
            app.SkullEditField.Value = 8000;
            app.CortexEditFieldLabel = uilabel(app.CommonparamsPanel);
            app.CortexEditFieldLabel.Text = 'Cortex:';
            app.CortexEditField = uieditfield(app.CommonparamsPanel, 'numeric');
            app.CortexEditField.Limits = [5000 15000];
            app.CortexEditField.Value = 8000;
            app.LayerdescriptorLabel = uilabel(app.CommonparamsPanel);
            app.LayerdescriptorLabel.Text = 'Layer descriptor:';
            app.AnatomyTemplateDropDown_3 = uidropdown(app.CommonparamsPanel);
            app.AnatomyTemplateDropDown_3.Items = {'--Select--', 'Pial', 'Midthickness', 'White', 'FS_LR', 'Bigbrain'};
            app.AnatomyTemplateDropDown_3.Value = '--Select--';
            app.MRItransformationLabel_2 = uilabel(app.CommonparamsPanel);
            app.MRItransformationLabel_2.Text = 'MRI transformation:';
            app.filenameLabel = uilabel(app.CommonparamsPanel);
            app.filenameLabel.Text = 'file name:';
            app.HCPTemplatenameEditField_6 = uieditfield(app.CommonparamsPanel, 'text');
            app.ImportChannelTab = uitab(app.TabGroup);
            app.ImportChannelTab.Title = 'Import Channel';
            app.SelectChanneloptionButtonGroup = uibuttongroup(app.ImportChannelTab);
            app.SelectChanneloptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectChanneloptionButtonGroupSelectionChanged, true);
            app.SelectChanneloptionButtonGroup.Title = 'Select Channel option';
            app.Button_Channel_template = uiradiobutton(app.SelectChanneloptionButtonGroup);
            app.Button_Channel_template.Text = '';
            app.Button_Channel_template.Value = true;
            app.Button_Channel_raw_data = uiradiobutton(app.SelectChanneloptionButtonGroup);
            app.Button_Channel_raw_data.Text = '';
            app.UseChanneltemplatePanel = uipanel(app.SelectChanneloptionButtonGroup);
            app.UseChanneltemplatePanel.Title = 'Use Channel template';
            app.GroupDropDownLabel = uilabel(app.UseChanneltemplatePanel);
            app.GroupDropDownLabel.Text = 'Group:';
            app.ChannGroupDropDown = uidropdown(app.UseChanneltemplatePanel);
            app.ChannGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannGroupDropDownValueChanged, true);
            app.TemplatenameDropDownLabel_2 = uilabel(app.UseChanneltemplatePanel);
            app.TemplatenameDropDownLabel_2.Text = 'Template name:';
            app.ChannTemplatenameDropDown = uidropdown(app.UseChanneltemplatePanel);
            app.ChannTemplatenameDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannTemplatenameDropDownValueChanged, true);
            app.Channel_UITable = uitable(app.UseChanneltemplatePanel);
            app.Channel_UITable.ColumnName = {'No.'; 'Name'; 'Type'; 'Location'; 'Comment'};
            app.Channel_UITable.RowName = {};
            app.UseChannelRawdataPanel = uipanel(app.SelectChanneloptionButtonGroup);
            app.UseChannelRawdataPanel.Enable = 'off';
            app.UseChannelRawdataPanel.Title = 'Use Raw data';
            app.SelectRawDataBPathButton = uibutton(app.UseChannelRawdataPanel, 'push');
            app.SelectRawDataBPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectRawDataBPathButtonPushed, true);
            app.SelectRawDataBPathButton.Text = 'Select';
            app.BasepathLabel = uilabel(app.UseChannelRawdataPanel);
            app.BasepathLabel.Text = 'Base path:';
            app.ChanRawDataBPathEditField = uieditfield(app.UseChannelRawdataPanel, 'text');
            app.NameLabel = uilabel(app.UseChannelRawdataPanel);
            app.NameLabel.Text = 'Name:';
            app.ChanRawDataFPathEditField = uieditfield(app.UseChannelRawdataPanel, 'text');
            app.FormatDropDownLabel = uilabel(app.UseChannelRawdataPanel);
            app.FormatDropDownLabel.Text = 'Format:';
            app.ChanFormatDropDown = uidropdown(app.UseChannelRawdataPanel);
            app.ChanFormatDropDown.Items = {'-Select-', 'mat', 'mff', 'edf', '4D'};
            app.ChanFormatDropDown.Value = '-Select-';
            app.IsFileSwitchLabel = uilabel(app.UseChannelRawdataPanel);
            app.IsFileSwitchLabel.Text = 'Is File:';
            app.IsFileSwitch = uiswitch(app.UseChannelRawdataPanel, 'slider');
            app.IsFileSwitch.Items = {'No', 'Yes'};
            app.IsFileSwitch.Value = 'Yes';
            app.ComputeHeadmodelTab = uitab(app.TabGroup);
            app.ComputeHeadmodelTab.Title = 'Compute Headmodel';
            app.MethodsButtonGroup = uibuttongroup(app.ComputeHeadmodelTab);
            app.MethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodsButtonGroupSelectionChanged, true);
            app.MethodsButtonGroup.Title = 'Methods';
            app.Button_DoNotImportData = uiradiobutton(app.MethodsButtonGroup);
            app.Button_DoNotImportData.Text = '';
            app.Button_DoNotImportData.Value = true;
            app.Button_ImportPrepData = uiradiobutton(app.MethodsButtonGroup);
            app.Button_ImportPrepData.Text = '';
            app.Button_PreUseRawData = uiradiobutton(app.MethodsButtonGroup);
            app.Button_PreUseRawData.Text = '';
            app.OverlappingSpheresPanel = uipanel(app.MethodsButtonGroup);
            app.OverlappingSpheresPanel.Title = 'Overlapping Spheres';
            app.OpenMEEGBEMPanel_2 = uipanel(app.MethodsButtonGroup);
            app.OpenMEEGBEMPanel_2.Enable = 'off';
            app.OpenMEEGBEMPanel_2.Title = 'OpenMEEG BEM';
            app.isAdjointSwitchLabel = uilabel(app.OpenMEEGBEMPanel_2);
            app.isAdjointSwitchLabel.Text = 'isAdjoint:';
            app.isAdjointSwitch = uiswitch(app.OpenMEEGBEMPanel_2, 'slider');
            app.isAdjointSwitch.Items = {'No', 'Yes'};
            app.isAdjointSwitch.Value = 'No';
            app.isAdaptativeSwitchLabel = uilabel(app.OpenMEEGBEMPanel_2);
            app.isAdaptativeSwitchLabel.Text = 'isAdaptative:';
            app.isAdaptativeSwitch = uiswitch(app.OpenMEEGBEMPanel_2, 'slider');
            app.isAdaptativeSwitch.Items = {'No', 'Yes'};
            app.isAdaptativeSwitch.Value = 'Yes';
            app.isSplitSwitchLabel = uilabel(app.OpenMEEGBEMPanel_2);
            app.isSplitSwitchLabel.Text = 'isSplit:';
            app.isSplitSwitch = uiswitch(app.OpenMEEGBEMPanel_2, 'slider');
            app.isSplitSwitch.Items = {'No', 'Yes'};
            app.isSplitSwitch.Value = 'No';
            app.SplitLengthSpinnerLabel = uilabel(app.OpenMEEGBEMPanel_2);
            app.SplitLengthSpinnerLabel.Text = 'SplitLength:';
            app.SplitLengthSpinner = uispinner(app.OpenMEEGBEMPanel_2);
            app.SplitLengthSpinner.Value = 4000;
            app.PrepUseRawDataPanel = uipanel(app.MethodsButtonGroup);
            app.PrepUseRawDataPanel.Enable = 'off';
            app.PrepUseRawDataPanel.Title = 'DUNEuro FEM';
            app.FemCondEditFieldLabel = uilabel(app.PrepUseRawDataPanel);
            app.FemCondEditFieldLabel.Text = 'FemCond:';
            app.FemCondEditField = uieditfield(app.PrepUseRawDataPanel, 'text');
            app.FemCondEditField.Value = '[1.79,0.0080,0.43]';
            app.FemSelectEditFieldLabel = uilabel(app.PrepUseRawDataPanel);
            app.FemSelectEditFieldLabel.Text = 'FemSelect:';
            app.FemSelectEditField = uieditfield(app.PrepUseRawDataPanel, 'text');
            app.FemSelectEditField.Value = '[1,1,1]';
            app.IsotropicSwitchLabel = uilabel(app.PrepUseRawDataPanel);
            app.IsotropicSwitchLabel.Text = 'Isotropic:';
            app.IsotropicSwitch = uiswitch(app.PrepUseRawDataPanel, 'slider');
            app.IsotropicSwitch.Items = {'No', 'Yes'};
            app.IsotropicSwitch.Value = 'Yes';
            app.UseTensorSwitchLabel = uilabel(app.PrepUseRawDataPanel);
            app.UseTensorSwitchLabel.Text = 'UseTensor:';
            app.UseTensorSwitch = uiswitch(app.PrepUseRawDataPanel, 'slider');
            app.UseTensorSwitch.Items = {'No', 'Yes'};
            app.UseTensorSwitch.Value = 'No';
            app.MethodLabel = uilabel(app.PrepUseRawDataPanel);
            app.MethodLabel.Text = 'Method:';
            app.MethodDropDown = uidropdown(app.PrepUseRawDataPanel);
            app.MethodDropDown.Items = {'iso2mesh', 'brain2mesh', 'simnibs', 'roast', 'fieldtrip'};
            app.MethodDropDown.Value = 'iso2mesh';
            app.MeshTypeDropDownLabel = uilabel(app.PrepUseRawDataPanel);
            app.MeshTypeDropDownLabel.Text = 'MeshType:';
            app.MeshTypeDropDown = uidropdown(app.PrepUseRawDataPanel);
            app.MeshTypeDropDown.Items = {'tetrahedral', 'hexahedral'};
            app.MeshTypeDropDown.Value = 'tetrahedral';
            app.MaxVolSpinnerLabel = uilabel(app.PrepUseRawDataPanel);
            app.MaxVolSpinnerLabel.Text = 'MaxVol:';
            app.MaxVolSpinner = uispinner(app.PrepUseRawDataPanel);
            app.MaxVolSpinner.Step = 0.01;
            app.MaxVolSpinner.Limits = [0 1];
            app.MaxVolSpinner.Value = 0.1;
            app.KeepRatioSpinnerLabel = uilabel(app.PrepUseRawDataPanel);
            app.KeepRatioSpinnerLabel.Text = 'KeepRatio:';
            app.KeepRatioSpinner = uispinner(app.PrepUseRawDataPanel);
            app.KeepRatioSpinner.Limits = [1 100];
            app.KeepRatioSpinner.Value = 100;
            app.MergeMethodDropDownLabel = uilabel(app.PrepUseRawDataPanel);
            app.MergeMethodDropDownLabel.Text = 'MergeMethod:';
            app.MergeMethodDropDown = uidropdown(app.PrepUseRawDataPanel);
            app.MergeMethodDropDown.Items = {'mergesurf', 'mergemesh'};
            app.MergeMethodDropDown.Value = 'mergesurf';
            app.VertexDensitySpinnerLabel = uilabel(app.PrepUseRawDataPanel);
            app.VertexDensitySpinnerLabel.Text = 'VertexDensity:';
            app.VertexDensitySpinner = uispinner(app.PrepUseRawDataPanel);
            app.VertexDensitySpinner.Step = 0.01;
            app.VertexDensitySpinner.Limits = [0 1];
            app.VertexDensitySpinner.Value = 0.5;
            app.NbVerticesSpinnerLabel = uilabel(app.PrepUseRawDataPanel);
            app.NbVerticesSpinnerLabel.Text = 'NbVertices:';
            app.NbVerticesSpinner = uispinner(app.PrepUseRawDataPanel);
            app.NbVerticesSpinner.Limits = [8000 20000];
            app.NbVerticesSpinner.ValueDisplayFormat = '%.0f';
            app.NbVerticesSpinner.Value = 15000;
            app.NodeShiftSpinnerLabel = uilabel(app.PrepUseRawDataPanel);
            app.NodeShiftSpinnerLabel.Text = 'NodeShift:';
            app.NodeShiftSpinner = uispinner(app.PrepUseRawDataPanel);
            app.NodeShiftSpinner.Step = 0.01;
            app.NodeShiftSpinner.Limits = [0 0.49];
            app.NodeShiftSpinner.Value = 0.3;
            app.DownsampleSpinnerLabel = uilabel(app.PrepUseRawDataPanel);
            app.DownsampleSpinnerLabel.Text = 'Downsample:';
            app.DownsampleSpinner = uispinner(app.PrepUseRawDataPanel);
            app.DownsampleSpinner.Limits = [1 5];
            app.DownsampleSpinner.Value = 3;
            app.CommonparamsPanel_2 = uipanel(app.ComputeHeadmodelTab);
            app.CommonparamsPanel_2.Title = 'Common params';
            app.RadiiEditFieldLabel = uilabel(app.CommonparamsPanel_2);
            app.RadiiEditFieldLabel.Text = 'Radii:';
            app.RadiiEditField = uieditfield(app.CommonparamsPanel_2, 'text');
            app.RadiiEditField.Value = '[0.88,0.93,1]';
            app.ConductivityEditFieldLabel = uilabel(app.CommonparamsPanel_2);
            app.ConductivityEditFieldLabel.Text = 'Conductivity:';
            app.ConductivityEditField = uieditfield(app.CommonparamsPanel_2, 'text');
            app.ConductivityEditField.Value = '[0.33,0.0042,0.33]';
            app.BemNamesEditFieldLabel = uilabel(app.CommonparamsPanel_2);
            app.BemNamesEditFieldLabel.Text = 'BemNames:';
            app.BemNamesEditField = uieditfield(app.CommonparamsPanel_2, 'text');
            app.BemNamesEditField.Value = '["Scalp","Skull","Brain"]';
            app.BemCondEditFieldLabel = uilabel(app.CommonparamsPanel_2);
            app.BemCondEditFieldLabel.Text = 'BemCond:';
            app.BemCondEditField = uieditfield(app.CommonparamsPanel_2, 'text');
            app.BemCondEditField.Value = '[1,0.0125,1]';
            app.BemSelectEditFieldLabel = uilabel(app.CommonparamsPanel_2);
            app.BemSelectEditFieldLabel.Text = 'BemSelect:';
            app.BemSelectEditField = uieditfield(app.CommonparamsPanel_2, 'text');
            app.BemSelectEditField.Value = '[true,true,true]';
            app.UsedefaultparametersSwitchLabel = uilabel(app.ComputeHeadmodelTab);
            app.UsedefaultparametersSwitchLabel.Text = 'Use default parameters:';
            app.UsedefaultparametersSwitch = uiswitch(app.ComputeHeadmodelTab, 'slider');
            app.UsedefaultparametersSwitch.Items = {'No', 'Yes'};
            app.UsedefaultparametersSwitch.Value = 'Yes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Params_configuration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end